classdef Grafice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlottingAppUIFigure          matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        CircuitParametersPanel       matlab.ui.container.Panel
        R1andR2EditFieldLabel        matlab.ui.control.Label
        R1andR2EditField             matlab.ui.control.NumericEditField
        C1andC2EditFieldLabel        matlab.ui.control.Label
        C1andC2EditField             matlab.ui.control.NumericEditField
        R1DD                         matlab.ui.control.DropDown
        C1DD                         matlab.ui.control.DropDown
        R3EditFieldLabel             matlab.ui.control.Label
        R3EditField                  matlab.ui.control.NumericEditField
        R3DD                         matlab.ui.control.DropDown
        R4EditFieldLabel             matlab.ui.control.Label
        R4EditField                  matlab.ui.control.NumericEditField
        R5EditFieldLabel             matlab.ui.control.Label
        R5EditField                  matlab.ui.control.NumericEditField
        R4DD                         matlab.ui.control.DropDown
        R5DD                         matlab.ui.control.DropDown
        PotDD                        matlab.ui.control.DropDown
        PotEditFieldLabel            matlab.ui.control.Label
        PotEditField                 matlab.ui.control.NumericEditField
        PotSliderLabel               matlab.ui.control.Label
        PotSlider                    matlab.ui.control.Slider
        ComputeandplotButton         matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        FCText                       matlab.ui.control.Label
        FCText_2                     matlab.ui.control.Label
        VpsLabel                     matlab.ui.control.Label
        VpsEditField                 matlab.ui.control.NumericEditField
        R1DD_2                       matlab.ui.control.DropDown
        ScopeParametersPanel         matlab.ui.container.Panel
        ScopeTimeSliderLabel         matlab.ui.control.Label
        ScopeTimeSlider              matlab.ui.control.Slider
        FineCheckBox                 matlab.ui.control.CheckBox
        CenterPanel                  matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UIAxes                       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        Image                        matlab.ui.control.Image
        CircuitSelectorPanel         matlab.ui.container.Panel
        CurrentCircuitDropDownLabel  matlab.ui.control.Label
        CurrentCircuitDropDown       matlab.ui.control.DropDown
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        R1 = 0;
        R2 = 0;
        R3=0;
        R4=0;
        R5=0;
        Pot = 0;
        Potk =0;
        C1 = 0;
        C2 =0;
        rDiode = 0.5e3;
        
        R12Mul = 0;
        R3Mul = 0;
        R4Mul =0;
        R5Mul =0;
        PotMul = 0;
        C12Mul =0;
        
        GraphsTime = 0;
        AGC = 0;
        
        
    end
    
    methods (Access = private)
        
        function getFormData(app)
            %
            app.R1 = app.R1andR2EditField.Value;
            app.R2 = app.R1andR2EditField.Value;
            app.C1 = app.C1andC2EditField.Value;
            app.C2 = app.C1andC2EditField.Value;
            app.R3 = app.R3EditField.Value;
            app.R4 = app.R4EditField.Value;
            
            
            app.R5 = app.R5EditField.Value;
            app.Pot = app.PotEditField.Value;
            app.Potk = app.PotSlider.Value/app.Pot;
            
            
            getMultiples(app);
            
            app.R1 = app.R1 * app.R12Mul;
            app.R2 = app.R2 * app.R12Mul;
            app.R3 = app.R3 * app.R3Mul;
            app.R4 = app.R4 * app.R4Mul;
        
            app.C1 = app.C1 * app.C12Mul;
            app.C2 = app.C2 * app.C12Mul;
            
            
            app.R5 = app.R5 * app.R5Mul;
            app.Pot = app.Pot * app.PotMul;
            
            
        end
        
        
        function plotResultNoAGC(app) 


            %FIRST PLOT
            app.GraphsTime = app.ScopeTimeSlider.Value;
            f1 = str2double(app.Label.Text);
            % frequency for sin1
            per = 1/f1; % period
            
            
            t = 0:per/100:10*per;
            
            sz = size(t);
            t2 = 0:1/1000:(sz(2)-1)/1000;
            
           
            y = 2 * pi * f1 * t; % variable definition domain for sin1
            
            stability = checkStability(app);


            lambda = randi([5,10],1,1);
            Amplitude = randi([14,15],1,1);
            
            if stability == 0
            plot(app.UIAxes, t, ODSine(Amplitude,y,15), '-b');
            elseif stability < 0
                   ampl = randi([14,15],1,1);
                   plot(app.UIAxes, t, ODSine(ampl .* exp(-lambda.*t2),y,15) , '-b');
            else
                   plot(app.UIAxes, t, ODSine(exp(lambda.*t2),y,15), '-b');
                   
            end
            
            
        end
        
        function updatePot(app)
            value = app.PotEditField.Value;
            app.PotSlider.Limits = [0,value];
        end
        
        function updateLabels(app)
          
            R1 = app.R1;
            C1 = app.C1;
            resfreq = 1 / (2*pi*R1*C1);
            app.Label.Text = "" + resfreq; 
            
            if app.AGC == 1
            updatePot(app);
            end
            
            if checkStability(app) ~= 0 && app.AGC == 0
                
            uialert(app.PlottingAppUIFigure,'Be careful, the system does not meet Barkhausen conditions. Size the resistances accordingly','Warning');
           
            end
        end
        
        
        function  getMultiples(app)
            
            app.R12Mul = app.returnMultiples(app.R1DD);
            app.C12Mul = app.returnMultiples(app.C1DD);
            app.R3Mul = app.returnMultiples(app.R3DD);
            app.R4Mul = app.returnMultiples(app.R4DD);
            
            if app.AGC == 1
            app.R5Mul = app.returnMultiples(app.R5DD);
            app.PotMul = app.returnMultiples(app.PotDD);
            end
            
            %app.R12Mul
            %app.C12Mul 
            %app.R3Mul 
            %app.R4Mul 
            %app.R5Mul 
            %app.PotMul 
            
            
        end
        
        function enableControls(app, AGC)
            
            if AGC == 1
            app.R5DD.Enable = 'on';
            app.R5EditField.Enable = 'on';
            app.PotEditField.Enable = "on";
            app.PotSlider.Enable = "on";
            app.R5EditFieldLabel.Enable = 'on';
            app.PotEditFieldLabel.Enable = 'on';
            app.PotSliderLabel.Enable = 'on';
            app.PotDD.Enable = 'on';
            app.Image.ImageSource = "OscillatorLTDiodes.jpg";
            else
            app.R5DD.Enable = 'off';
            app.R5EditField.Enable = 'off';
            app.PotEditField.Enable = "off";
            app.PotSlider.Enable = "off";
            app.R5EditFieldLabel.Enable = 'off';
            app.PotEditFieldLabel.Enable = 'off';
            app.PotSliderLabel.Enable = 'off';
            app.PotDD.Enable = 'off';
            app.Image.ImageSource = "OscillatorLT.jpg";
            end
        end
        
        function stable = checkStability(app)
            
            R3 = app.R3;
            R4 = app.R4;
            conditionStab = 1 + R4 ./ R3;


            if conditionStab == 3
                stable = 0;
            elseif conditionStab < 3
                stable = -1;
            else stable = 1;
           
            end
            
            
        end
        
        function plotResultAGC(app)
            
             %FIRST PLOT
            app.GraphsTime = app.ScopeTimeSlider.Value;
            f1 = str2double(app.Label.Text);
            % frequency for sin1
            per = 1/f1; % period
            t = 0:per/100:10*per;


            Amplitude = 0.7 * (app.R3 + app.R5 + (app.Pot - app.Potk*app.Pot) + app.R4) / app.R4;
            
            (app.Pot - app.Potk*app.Pot)
            Amplitude
            app.AGC
           
            y = 2 * pi * f1 * t; % variable definition domain for sin1


            plot(app.UIAxes, t,ODSine(Amplitude,y,15), '-b');
   
        end
end

methods(Static)
    
    function result = returnMultiples(DDItem)


            if(DDItem.Value == "KOhm")
                result = 1e3;return;
            end
            if(DDItem.Value == "Ohm")
                result = 1; return;
            end
            
            if(DDItem.Value == "MOhm")
                result = 1e6; return;
            end
             
            if(DDItem.Value == "F")
                result = 1; return;
            end
             
            if(DDItem.Value == "mF")
                result = 1e-3; return;
            end
             
            if(DDItem.Value == "uF")
                result = 1e-6; return;
            end
           
             
            if(DDItem.Value == "nF")
                result = 1e-9; return;
            end
            
             if(DDItem.Value == "pF")
                result = 1e-12; return;
            end


        end
    
    
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ScopeTimeSlider.Limits = [1e-3,1e-1];
            getFormData(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PlottingAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {552, 552, 552};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {552, 552};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {277, '1x', 292};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: PotDD
        function PotDDValueChanged(app, event)
            value = app.PotDD.Value;
        end

        % Value changed function: PotEditField
        function PotEditFieldValueChanged(app, event)
            updatePot(app);
        end

        % Button pushed function: ComputeandplotButton
        function ComputeandplotButtonPushed(app, event)
          
            getFormData(app);
           
            
            updateLabels(app);
             if app.AGC == 0
                plotResultNoAGC(app);
             else
                 plotResultAGC(app);
             end
        end

        % Value changed function: PotSlider
        function PotSliderValueChanged(app, event)
            value = app.PotSlider.Value;
            app.Potk = value/ app.Pot;
             getFormData(app);
           
            
            updateLabels(app);
             if app.AGC == 0
                plotResultNoAGC(app);
             else
                 plotResultAGC(app);
             end
            
        end

        % Value changed function: R1DD
        function R1DDValueChanged(app, event)
            value = app.R1DD.Value;
            getFormData(app);
        end

        % Value changed function: ScopeTimeSlider
        function ScopeTimeSliderValueChanged(app, event)
            value = app.ScopeTimeSlider.Value;
            app.UIAxes.XLim = [0,value];
        end

        % Value changed function: CurrentCircuitDropDown
        function CurrentCircuitDropDownValueChanged(app, event)
            value = app.CurrentCircuitDropDown.Value;
            if value == "Without AGC";
                app.AGC = 0;
            else 
                app.AGC = 1;
            end
        
            enableControls(app, app.AGC);
        end

        % Value changed function: FineCheckBox
        function FineCheckBoxValueChanged(app, event)
            value = app.FineCheckBox.Value;
            if value == 1
                app.ScopeTimeSlider.Limits = [1e-3,1e-1];
            else
                app.ScopeTimeSlider.Limits = [1,100];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlottingAppUIFigure = uifigure('Visible', 'off');
            app.PlottingAppUIFigure.Name = 'Plotting App';
            app.PlottingAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PlottingAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.CircuitParametersPanel = uipanel(app.GridLayout3);
            app.CircuitParametersPanel.Title = 'Circuit Parameters';
            app.R1andR2EditFieldLabel = uilabel(app.CircuitParametersPanel);
            app.R1andR2EditFieldLabel.Text = 'R1 and R2';
            app.R1andR2EditField = uieditfield(app.CircuitParametersPanel, 'numeric');
            app.R1andR2EditField.Limits = [0 1000000];
            app.R1andR2EditField.Value = 10;
            app.C1andC2EditFieldLabel = uilabel(app.CircuitParametersPanel);
            app.C1andC2EditFieldLabel.Text = 'C1 and C2';
            app.C1andC2EditField = uieditfield(app.CircuitParametersPanel, 'numeric');
            app.C1andC2EditField.Limits = [0 1000000];
            app.C1andC2EditField.Value = 10;
            app.R1DD = uidropdown(app.CircuitParametersPanel);
            app.R1DD.Items = {'Ohm', 'KOhm', 'MOhm', ''};
            app.R1DD.ValueChangedFcn = createCallbackFcn(app, @R1DDValueChanged, true);
            app.R1DD.Value = 'KOhm';
            app.C1DD = uidropdown(app.CircuitParametersPanel);
            app.C1DD.Items = {'pF', 'nF', 'uF', 'F', ''};
            app.C1DD.Value = 'nF';
            app.R3EditFieldLabel = uilabel(app.CircuitParametersPanel);
            app.R3EditFieldLabel.Text = 'R3';
            app.R3EditField = uieditfield(app.CircuitParametersPanel, 'numeric');
            app.R3EditField.Value = 10;
            app.R3DD = uidropdown(app.CircuitParametersPanel);
            app.R3DD.Items = {'Ohm', 'KOhm', 'MOhm', ''};
            app.R3DD.Value = 'KOhm';
            app.R4EditFieldLabel = uilabel(app.CircuitParametersPanel);
            app.R4EditFieldLabel.Text = 'R4';
            app.R4EditField = uieditfield(app.CircuitParametersPanel, 'numeric');
            app.R4EditField.Limits = [0 100000];
            app.R4EditField.Value = 20;
            app.R5EditFieldLabel = uilabel(app.CircuitParametersPanel);
            app.R5EditFieldLabel.Enable = 'off';
            app.R5EditFieldLabel.Text = 'R5';
            app.R5EditField = uieditfield(app.CircuitParametersPanel, 'numeric');
            app.R5EditField.Limits = [0 100000];
            app.R5EditField.Enable = 'off';
            app.R5EditField.Value = 5;
            app.R4DD = uidropdown(app.CircuitParametersPanel);
            app.R4DD.Items = {'Ohm', 'KOhm', 'MOhm', ''};
            app.R4DD.Value = 'KOhm';
            app.R5DD = uidropdown(app.CircuitParametersPanel);
            app.R5DD.Items = {'Ohm', 'KOhm', 'MOhm', ''};
            app.R5DD.Enable = 'off';
            app.R5DD.Value = 'KOhm';
            app.PotDD = uidropdown(app.CircuitParametersPanel);
            app.PotDD.Items = {'Ohm', 'KOhm', 'MOhm', ''};
            app.PotDD.ValueChangedFcn = createCallbackFcn(app, @PotDDValueChanged, true);
            app.PotDD.Enable = 'off';
            app.PotDD.Value = 'KOhm';
            app.PotEditFieldLabel = uilabel(app.CircuitParametersPanel);
            app.PotEditFieldLabel.Enable = 'off';
            app.PotEditFieldLabel.Text = 'Pot';
            app.PotEditField = uieditfield(app.CircuitParametersPanel, 'numeric');
            app.PotEditField.Limits = [0 100000];
            app.PotEditField.ValueChangedFcn = createCallbackFcn(app, @PotEditFieldValueChanged, true);
            app.PotEditField.Enable = 'off';
            app.PotEditField.Value = 10;
            app.PotSliderLabel = uilabel(app.CircuitParametersPanel);
            app.PotSliderLabel.Enable = 'off';
            app.PotSliderLabel.Text = 'Pot';
            app.PotSlider = uislider(app.CircuitParametersPanel);
            app.PotSlider.ValueChangedFcn = createCallbackFcn(app, @PotSliderValueChanged, true);
            app.PotSlider.Enable = 'off';
            app.ComputeandplotButton = uibutton(app.CircuitParametersPanel, 'push');
            app.ComputeandplotButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeandplotButtonPushed, true);
            app.ComputeandplotButton.Text = 'Compute and plot';
            app.Label = uilabel(app.CircuitParametersPanel);
            app.Label.Text = '0';
            app.FCText = uilabel(app.CircuitParametersPanel);
            app.FCText.Text = 'F0 = ';
            app.FCText_2 = uilabel(app.CircuitParametersPanel);
            app.FCText_2.Text = 'Hz';
            app.VpsLabel = uilabel(app.CircuitParametersPanel);
            app.VpsLabel.Enable = 'off';
            app.VpsLabel.Text = '+-Vps';
            app.VpsEditField = uieditfield(app.CircuitParametersPanel, 'numeric');
            app.VpsEditField.Limits = [0 1000000];
            app.VpsEditField.Editable = 'off';
            app.VpsEditField.Enable = 'off';
            app.VpsEditField.Value = 15;
            app.R1DD_2 = uidropdown(app.CircuitParametersPanel);
            app.R1DD_2.Items = {'Volts', ''};
            app.R1DD_2.Enable = 'off';
            app.R1DD_2.Value = 'Volts';
            app.ScopeParametersPanel = uipanel(app.GridLayout3);
            app.ScopeParametersPanel.Title = 'Scope Parameters';
            app.ScopeTimeSliderLabel = uilabel(app.ScopeParametersPanel);
            app.ScopeTimeSliderLabel.Text = 'Scope Time';
            app.ScopeTimeSlider = uislider(app.ScopeParametersPanel);
            app.ScopeTimeSlider.Limits = [500 10000];
            app.ScopeTimeSlider.MajorTicks = [0.001 1];
            app.ScopeTimeSlider.ValueChangedFcn = createCallbackFcn(app, @ScopeTimeSliderValueChanged, true);
            app.ScopeTimeSlider.MinorTicks = [];
            app.ScopeTimeSlider.Value = 3000;
            app.FineCheckBox = uicheckbox(app.ScopeParametersPanel);
            app.FineCheckBox.ValueChangedFcn = createCallbackFcn(app, @FineCheckBoxValueChanged, true);
            app.FineCheckBox.Text = 'Fine';
            app.FineCheckBox.Value = true;
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Vout')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-20 20];
            app.UIAxes.ZLim = [0 1];
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = 'OscillatorLT.jpg';
            app.CircuitSelectorPanel = uipanel(app.GridLayout4);
            app.CircuitSelectorPanel.Title = 'Circuit Selector';
            app.CurrentCircuitDropDownLabel = uilabel(app.CircuitSelectorPanel);
            app.CurrentCircuitDropDownLabel.Text = 'Current Circuit';
            app.CurrentCircuitDropDown = uidropdown(app.CircuitSelectorPanel);
            app.CurrentCircuitDropDown.Items = {'Without AGC', 'With AGC'};
            app.CurrentCircuitDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrentCircuitDropDownValueChanged, true);
            app.CurrentCircuitDropDown.Value = 'Without AGC';
            % Show the figure after all components are created
            app.PlottingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Grafice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlottingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlottingAppUIFigure)
        end
    end
end