classdef ImageWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TheoryUIFigure  matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        Image           matlab.ui.control.Image
        NextButton      matlab.ui.control.Button
        TextArea        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        TheoryFiles = ["Theory1.txt"; "Theory2.txt"];
        ImageFiles = ["Drawing1.jpg" ; "Drawing2.jpg"];
        currentDisplay = 1; % Description
    end
    
    methods (Access = private)
        
        function text = getText(app, filename)
            
            
           text = fileread(filename); 
           
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            txt = getText(app, app.TheoryFiles(app.currentDisplay));
            app.Image.ImageSource = app.ImageFiles(app.currentDisplay);
            
            
           app.TextArea.Value = txt;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.currentDisplay = mod(app.currentDisplay,2)+1;
            startupFcn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TheoryUIFigure = uifigure('Visible', 'off');
            app.TheoryUIFigure.Name = 'Theory';
            app.GridLayout = uigridlayout(app.TheoryUIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'OscillatorLT.jpg';
            app.NextButton = uibutton(app.GridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = {'Next'; ''};
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.TheoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TheoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TheoryUIFigure)
        end
    end
end