classdef MainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        WienBridgeSinusoidalOscillatorLabel  matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        PresentationButton             matlab.ui.control.Button
        GraphsAppButton                matlab.ui.control.Button
        DocumentationButton            matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        CopyrightBentaAndrei2020Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageWindowInst = ImageWindow.empty;
        GraphsWindow = Grafice.empty;% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PresentationButton
        function PresentationButtonPushed(app, event)
            app.ImageWindowInst = ImageWindow;
        end

        % Button pushed function: DocumentationButton
        function DocumentationButtonPushed(app, event)
            open('Documentation.docx');
        end

        % Button pushed function: GraphsAppButton
        function GraphsAppButtonPushed(app, event)
            
            app.GraphsWindow = Grafice;
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            app.ImageWindowInst
            
            selection = uiconfirm(app.UIFigure,'Are you sure you want to quit the app??','Confirm Close',...
                        'Icon','warning');
                    
            if selection == "OK"
                %if app.ImageWindowInst ~= ImageWindow.empty
                    app.ImageWindowInst.delete();  
                %end
                %if app.GraphsWindow ~= Grafice.empty
                    app.GraphsWindow.delete();
                %end
                app.delete();
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Wien Bridge';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.WienBridgeSinusoidalOscillatorLabel = uilabel(app.GridLayout);
            app.WienBridgeSinusoidalOscillatorLabel.Text = {'Wien Bridge'; 'Sinusoidal Oscillator'; ''};
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PresentationButton = uibutton(app.GridLayout2, 'push');
            app.PresentationButton.ButtonPushedFcn = createCallbackFcn(app, @PresentationButtonPushed, true);
            app.PresentationButton.Text = 'Presentation';
            app.GraphsAppButton = uibutton(app.GridLayout2, 'push');
            app.GraphsAppButton.ButtonPushedFcn = createCallbackFcn(app, @GraphsAppButtonPushed, true);
            app.GraphsAppButton.Text = 'Graphs App';
            app.DocumentationButton = uibutton(app.GridLayout2, 'push');
            app.DocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @DocumentationButtonPushed, true);
            app.DocumentationButton.Text = 'Documentation';
            app.ExitButton = uibutton(app.GridLayout2, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.CopyrightBentaAndrei2020Label = uilabel(app.GridLayout);
            app.CopyrightBentaAndrei2020Label.Text = 'Copyright Benta Andrei, 2020';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end